classdef ICPro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ICProUIFigure           matlab.ui.Figure
        SampletypeSwitchLabel   matlab.ui.control.Label
        Panel_TE                matlab.ui.container.Panel
        Button_RawFile          matlab.ui.control.Button
        Field_RawFile           matlab.ui.control.EditField
        Field_SIDTE             matlab.ui.control.EditField
        Field_BlankID           matlab.ui.control.EditField
        Field_SpikeID           matlab.ui.control.EditField
        Label_SIDTE             matlab.ui.control.Label
        Label_BlankID           matlab.ui.control.Label
        Label_SpikeID           matlab.ui.control.Label
        Field_RinseID           matlab.ui.control.EditField
        Label_RinseID           matlab.ui.control.Label
        Field_QCID              matlab.ui.control.EditField
        Label_QCID              matlab.ui.control.Label
        Panel_TECorr            matlab.ui.container.Panel
        Check_RC                matlab.ui.control.CheckBox
        Check_QC                matlab.ui.control.CheckBox
        Check_SC                matlab.ui.control.CheckBox
        Check_OC                matlab.ui.control.CheckBox
        Check_BC                matlab.ui.control.CheckBox
        Check_DC                matlab.ui.control.CheckBox
        Panel_ThU               matlab.ui.container.Panel
        Field_NBlocks           matlab.ui.control.EditField
        Field_NSamples          matlab.ui.control.EditField
        Label_NBlocks           matlab.ui.control.Label
        Label_NSamples          matlab.ui.control.Label
        Field_ICPBlankID        matlab.ui.control.EditField
        Label_ICPBlankID        matlab.ui.control.Label
        Field_ICPNatUID         matlab.ui.control.EditField
        Label_ICPNatUID         matlab.ui.control.Label
        Field_SIDThU            matlab.ui.control.EditField
        Label_SIDThU            matlab.ui.control.Label
        Button_RawFolder        matlab.ui.control.Button
        Field_RawFolder         matlab.ui.control.EditField
        Panel_IsotopicDilution  matlab.ui.container.Panel
        SpikesPanel             matlab.ui.container.Panel
        UspikeDropDownLabel     matlab.ui.control.Label
        DropDown_236U           matlab.ui.control.DropDown
        ThspikeDropDown_2Label  matlab.ui.control.Label
        DropDown_230Th          matlab.ui.control.DropDown
        ThspikeDropDownLabel    matlab.ui.control.Label
        DropDown_229Th          matlab.ui.control.DropDown
        Field_229ThRY           matlab.ui.control.NumericEditField
        Label_RX                matlab.ui.control.Label
        Field_229ThRX           matlab.ui.control.NumericEditField
        Label_CY                matlab.ui.control.Label
        Field_229ThCY           matlab.ui.control.NumericEditField
        Field_230ThRY           matlab.ui.control.NumericEditField
        Field_236URY            matlab.ui.control.NumericEditField
        Field_230ThRX           matlab.ui.control.NumericEditField
        Field_236URX            matlab.ui.control.NumericEditField
        Field_230ThCY           matlab.ui.control.NumericEditField
        Field_236UCY            matlab.ui.control.NumericEditField
        Label_RY                matlab.ui.control.Label
        Button_Edit229Th        matlab.ui.control.Button
        Button_Edit230Th        matlab.ui.control.Button
        Button_Edit236U         matlab.ui.control.Button
        Button_Save229Th        matlab.ui.control.Button
        Button_Save230Th        matlab.ui.control.Button
        Button_Save236U         matlab.ui.control.Button
        Button_Remove229Th      matlab.ui.control.Button
        Button_Remove230Th      matlab.ui.control.Button
        Button_Remove236U       matlab.ui.control.Button
        Footnote                matlab.ui.control.Label
        TabGroup                matlab.ui.container.TabGroup
        Tab_IDWeights           matlab.ui.container.Tab
        Field_Sediment          matlab.ui.control.TextArea
        Label_Sediment          matlab.ui.control.Label
        Field_4ml               matlab.ui.control.TextArea
        Field_10ml              matlab.ui.control.TextArea
        Field_400ul             matlab.ui.control.TextArea
        Field_ThBC              matlab.ui.control.TextArea
        Label_4ml               matlab.ui.control.Label
        Label_10ml              matlab.ui.control.Label
        Label_400ul             matlab.ui.control.Label
        Label_ThBC              matlab.ui.control.Label
        Field_UBC               matlab.ui.control.TextArea
        Field_ThSC              matlab.ui.control.TextArea
        Label_UBC               matlab.ui.control.Label
        Label_ThSC              matlab.ui.control.Label
        Field_USC               matlab.ui.control.TextArea
        Label_USC               matlab.ui.control.Label
        Field_SampleList        matlab.ui.control.TextArea
        Label_SampleList        matlab.ui.control.Label
        Button_MakeList         matlab.ui.control.Button
        Tab_RawIntensities      matlab.ui.container.Tab
        Field_cpsSampleList     matlab.ui.control.TextArea
        Label_cpsSampleList     matlab.ui.control.Label
        Field_cps230Th          matlab.ui.control.TextArea
        Label_cps230Th          matlab.ui.control.Label
        Field_cps232Th          matlab.ui.control.TextArea
        Field_cps236U           matlab.ui.control.TextArea
        Field_cps238U           matlab.ui.control.TextArea
        Label_cps232Th          matlab.ui.control.Label
        Label_cps236U           matlab.ui.control.Label
        Label_cps238U           matlab.ui.control.Label
        Label_cps230Th_R        matlab.ui.control.Label
        Label_cps232Th_R        matlab.ui.control.Label
        Label_cps236U_R         matlab.ui.control.Label
        Label_cps238U_R         matlab.ui.control.Label
        Field_cps230Th_R        matlab.ui.control.TextArea
        Field_cps232Th_R        matlab.ui.control.TextArea
        Field_cps236U_R         matlab.ui.control.TextArea
        Field_cps238U_R         matlab.ui.control.TextArea
        Footnote2               matlab.ui.control.Label
        Tab_TEresults           matlab.ui.container.Tab
        DataSelectTE            matlab.ui.container.ButtonGroup
        RawButton               matlab.ui.control.ToggleButton
        RCButton                matlab.ui.control.ToggleButton
        QCButton                matlab.ui.control.ToggleButton
        SCButton                matlab.ui.control.ToggleButton
        OCButton                matlab.ui.control.ToggleButton
        BCButton                matlab.ui.control.ToggleButton
        DCButton                matlab.ui.control.ToggleButton
        IDButton                matlab.ui.control.ToggleButton
        Table_TEresults         matlab.ui.control.Table
        Radio_ConcErrsTE        matlab.ui.container.ButtonGroup
        Conc                    matlab.ui.control.RadioButton
        ErrsTE                  matlab.ui.control.RadioButton
        Button_SaveTEresults    matlab.ui.control.Button
        Tab_TEplot              matlab.ui.container.Tab
        SelectDropDown          matlab.ui.control.DropDown
        ExportfigureButton      matlab.ui.control.Button
        ThIDFig                 matlab.ui.control.UIAxes
        UIDFig                  matlab.ui.control.UIAxes
        Tab_ThUresults          matlab.ui.container.Tab
        Table_ThUresults        matlab.ui.control.Table
        DataSelectThU           matlab.ui.container.ButtonGroup
        UCSButton               matlab.ui.control.ToggleButton
        MBSButton               matlab.ui.control.ToggleButton
        UCNButton               matlab.ui.control.ToggleButton
        MBNButton               matlab.ui.control.ToggleButton
        NatUU235U234Label       matlab.ui.control.Label
        Radio_ConcErrsThU       matlab.ui.container.ButtonGroup
        ConcThU                 matlab.ui.control.RadioButton
        ErrsThU                 matlab.ui.control.RadioButton
        Button_SaveThUresults   matlab.ui.control.Button
        Th229230U236234Label    matlab.ui.control.Label
        Tab_Fluxes              matlab.ui.container.Tab
        Button_TEtoggle         matlab.ui.control.StateButton
        Button_ThUtoggle        matlab.ui.control.StateButton
        Button_Run              matlab.ui.control.Button
        Switch_SType            matlab.ui.control.Switch
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_Run
        function Execute(app, event)
            AppPath = dir([mfilename('fullpath'),'.mlapp']);
            cd([AppPath.folder,'/functions/'])
            %% GET INPUT DATA
            % Trace element parameters
            TEpar.RawPath = app.Field_RawFile.Value;
            TEpar.SID = app.Field_SIDTE.Value;
            TEpar.BID = app.Field_BlankID.Value;
            TEpar.VID = app.Field_SpikeID.Value;
            TEpar.RID = app.Field_RinseID.Value;
            TEpar.QID = app.Field_QCID.Value;
            TEpar.CCheck.RC = app.Check_RC.Value;
            TEpar.CCheck.QC = app.Check_QC.Value;
            TEpar.CCheck.SC = app.Check_SC.Value;
            TEpar.CCheck.OC = app.Check_OC.Value;
            TEpar.CCheck.BC = app.Check_BC.Value;
            TEpar.CCheck.DC = app.Check_DC.Value;
            % Th/U parameters
            ThUpar.ThURawPath = app.Field_RawFolder.Value;
            ThUpar.SType = app.Switch_SType.Value;
            ThUpar.NBlocks = str2double(app.Field_NBlocks.Value);
            ThUpar.NSamples = str2double(app.Field_NSamples.Value);
            ThUpar.SID = app.Field_SIDThU.Value;
            ThUpar.BID = app.Field_ICPBlankID.Value;
            ThUpar.NatUID = app.Field_ICPNatUID.Value;
            ThUpar.IDsel = 0;
            % Isotopic dilution parameters (weights, spikes & raw intensities)
            WtPar.m_sed = str2double(app.Field_Sediment.Value)';
            WtPar.m_4ml = str2double(app.Field_4ml.Value)';
            WtPar.m_10ml = str2double(app.Field_10ml.Value)';
            WtPar.m_400ul = str2double(app.Field_400ul.Value)';
            WtPar.m_ThSC = str2double(app.Field_ThSC.Value)';
            WtPar.m_ThBC = str2double(app.Field_ThBC.Value)';
            WtPar.m_USC = str2double(app.Field_USC.Value)';
            WtPar.m_UBC = str2double(app.Field_UBC.Value)';
            SpikePar.Th229RY = app.Field_229ThRY.Value;
            SpikePar.Th229RX = app.Field_229ThRX.Value;
            SpikePar.Th229CY = app.Field_229ThCY.Value;
            SpikePar.Th230RY = app.Field_230ThRY.Value;
            SpikePar.Th230RX = app.Field_230ThRX.Value;
            SpikePar.Th230CY = app.Field_230ThCY.Value;
            SpikePar.U236RY = app.Field_236URY.Value;
            SpikePar.U236RX = app.Field_236URX.Value;
            SpikePar.U236CY = app.Field_236UCY.Value;
            cps.Sample = [str2double(app.Field_cps230Th.Value),...
                str2double(app.Field_cps232Th.Value),...
                str2double(app.Field_cps236U.Value),...
                str2double(app.Field_cps238U.Value)]';
            cps.Rinse = [str2double(app.Field_cps230Th_R.Value),...
                str2double(app.Field_cps232Th_R.Value),...
                str2double(app.Field_cps236U_R.Value),...
                str2double(app.Field_cps238U_R.Value)]';
            
            %% DATA PROCESSING
            if app.Button_ThUtoggle.Value == 0 && app.Button_TEtoggle.Value == 1
                % Read & process TE raw data
                [C_TEr,dC_TEr,Isotopes,RunID] = TE_ReadRaw(TEpar,1);
                [C_TE, dC_TE, IsotopesID] = TE_Processor(TEpar,C_TEr,dC_TEr,Isotopes,RunID,WtPar,SpikePar,cps);
                setappdata(app.ICProUIFigure,'C_TE',C_TE)
                setappdata(app.ICProUIFigure,'dC_TE',dC_TE)
                setappdata(app.ICProUIFigure,'Isotopes',Isotopes)
                setappdata(app.ICProUIFigure,'IsotopesID',IsotopesID)
                % Display results in table
                app.Table_TEresults.Data = [C_TE.DC.f_DC',C_TE.ID.Sample'];
                app.Table_TEresults.ColumnName = ['Dilution factor';IsotopesID];
                app.Table_TEresults.RowName = RunID.Sample;
                app.Radio_ConcErrsTE.SelectedObject = app.ConcTE;
                % Plot ID vs linear calibration
                iRTh232 = find(strcmp(IsotopesID,'Th232(ID)'));
                iRU238 = find(strcmp(IsotopesID,'U238(ID)'));
                plot(app.ThIDFig,C_TE.DC.Sample(iRTh232,1:end),C_TE.ID.Sample(iRTh232,1:end),'.','MarkerSize',15)
                plot(app.UIDFig,C_TE.DC.Sample(iRU238,1:end),C_TE.ID.Sample(iRU238,1:end),'.','MarkerSize',15)
            elseif app.Button_ThUtoggle.Value == 1 && app.Button_TEtoggle.Value == 0
                % Process ThU raw data
                [ThU,dThU,SampleSeq,NatUSeq] = ThU_Processor(ThUpar);
                setappdata(app.ICProUIFigure,'ThU',ThU)
                setappdata(app.ICProUIFigure,'dThU',dThU)
                setappdata(app.ICProUIFigure,'SampleSeq',SampleSeq)
                setappdata(app.ICProUIFigure,'NatUSeq',NatUSeq)
                % Display results in table
                app.Table_ThUresults.Data = [ThU.Sample.R229_230_cMB(2:end)',ThU.Sample.R236_234_cMB(2:end)'];
                app.Table_ThUresults.ColumnName = {'Th229/Th230','U236/U234'};
                app.Table_ThUresults.RowName = SampleSeq(2:end);
                app.Radio_ConcErrsThU.SelectedObject = app.ConcThU;
            elseif app.Button_ThUtoggle.Value == 1 && app.Button_TEtoggle.Value == 1
                warndlg('Stay tuned!')
            else
                warndlg('No analyses performed.')
            end
            
        end

        % Value changed function: SelectDropDown
        function ChangePlot(app, event)
            if strcmp(app.SelectDropDown.Value, 'Th230')
                app.ThIDFig.Visible = 'on';
                app.UIDFig.Visible = 'off';
            elseif strcmp(app.SelectDropDown.Value, 'U236')
                app.ThIDFig.Visible = 'off';
                app.UIDFig.Visible = 'on';
            end
        end

        % Selection changed function: DataSelectTE
        function SwitchResultTE(app, event)
            Selection = app.DataSelectTE.SelectedObject.Tag;
            C_TE = getappdata(app.ICProUIFigure,'C_TE');
            dC_TE = getappdata(app.ICProUIFigure,'dC_TE');
            if contains(app.Radio_ConcErrsTE.SelectedObject.Text,'Concentrations')
                if (strcmp(Selection,'ID') || strcmp(Selection,'DC')) && app.Check_DC.Value == 1
                    app.Table_TEresults.Data = [C_TE.DC.f_DC',C_TE.(Selection).Sample'];
                else
                    app.Table_TEresults.Data = C_TE.(Selection).Sample';
                end
            elseif contains(app.Radio_ConcErrsTE.SelectedObject.Text,'Errors')
                if (strcmp(Selection,'ID') || strcmp(Selection,'DC')) && app.Check_DC.Value == 1
                    app.Table_TEresults.Data = [dC_TE.DC.f_DC',dC_TE.(Selection).Sample'];
                else
                    app.Table_TEresults.Data = dC_TE.(Selection).Sample';
                end
            end
            setappdata(app.ICProUIFigure,'ActiveResultTE',Selection);
            
            if (strcmp(Selection,'ID')) && app.Check_DC.Value == 1
                IsotopesID = getappdata(app.ICProUIFigure,'IsotopesID');
                app.Table_TEresults.ColumnName = ['Dilution factor';IsotopesID];
            elseif (strcmp(Selection,'ID')) && app.Check_DC.Value == 0
                IsotopesID = getappdata(app.ICProUIFigure,'IsotopesID');
                app.Table_TEresults.ColumnName = IsotopesID;
            elseif strcmp(Selection,'DC')
                Isotopes = getappdata(app.ICProUIFigure,'Isotopes');
                app.Table_TEresults.ColumnName = ['Dilution factor';Isotopes];
            else
                Isotopes = getappdata(app.ICProUIFigure,'Isotopes');
                app.Table_TEresults.ColumnName = Isotopes;
            end
        end

        % Selection changed function: DataSelectThU
        function SwitchResultThU(app, event)
            Selection = app.DataSelectThU.SelectedObject.Tag;
            ThU = getappdata(app.ICProUIFigure,'ThU');
            dThU = getappdata(app.ICProUIFigure,'dThU');
            SampleSeq = getappdata(app.ICProUIFigure,'SampleSeq');
            NatUSeq = getappdata(app.ICProUIFigure,'NatUSeq');
            if contains(app.Radio_ConcErrsThU.SelectedObject.Text,'Concentrations')
                if strcmp(Selection,'SampleUC')
                    app.Table_ThUresults.Data = [ThU.Sample.R229_230(2:end)',ThU.Sample.R236_234(2:end)'];
                elseif strcmp(Selection,'SampleMB')
                    app.Table_ThUresults.Data = [ThU.Sample.R229_230_cMB(2:end)',ThU.Sample.R236_234_cMB(2:end)'];
                elseif strcmp(Selection,'NatUUC')
                    app.Table_ThUresults.Data = ThU.NatU.R235_234(2:end);
                elseif strcmp(Selection,'NatUMB')
                    app.Table_ThUresults.Data = ThU.NatU.R235_234_cMB(2:end);
                end
            elseif contains(app.Radio_ConcErrsThU.SelectedObject.Text,'Errors')
                if strcmp(Selection,'SampleUC')
                    app.Table_ThUresults.Data = [dThU.Sample.R229_230(2:end)',dThU.Sample.R236_234(2:end)'];
                elseif strcmp(Selection,'SampleMB')
                    app.Table_ThUresults.Data = [dThU.Sample.R229_230_cMB(2:end)',dThU.Sample.R236_234_cMB(2:end)'];
                elseif strcmp(Selection,'NatUUC')
                    app.Table_ThUresults.Data = dThU.NatU.R235_234(2:end);
                elseif strcmp(Selection,'NatUMB')
                    app.Table_ThUresults.Data = dThU.NatU.R235_234_cMB(2:end);
                end
            end
            if strcmp(Selection,'SampleUC') || strcmp(Selection,'SampleMB')
                app.Table_ThUresults.ColumnName = {'Th229/Th230','U236/U234'};
                app.Table_ThUresults.RowName = SampleSeq(2:end);
            elseif strcmp(Selection,'NatUUC') || strcmp(Selection,'NatUMB')
                app.Table_ThUresults.ColumnName = {'U235/U234'};
                app.Table_ThUresults.RowName = NatUSeq;
            end
            setappdata(app.ICProUIFigure,'ActiveResultThU',Selection);
        end

        % Selection changed function: Radio_ConcErrsTE
        function ConcErrSwitchTE(app, event)
            Selection = getappdata(app.ICProUIFigure,'ActiveResultTE');
            C_TE = getappdata(app.ICProUIFigure,'C_TE');
            dC_TE = getappdata(app.ICProUIFigure,'dC_TE');
            if contains(app.Radio_ConcErrsTE.SelectedObject.Text,'Concentrations')
                if (strcmp(Selection,'ID') || strcmp(Selection,'DC')) && app.Check_DC.Value == 1
                    app.Table_TEresults.Data = [C_TE.DC.f_DC',C_TE.(Selection).Sample'];
                else
                    app.Table_TEresults.Data = C_TE.(Selection).Sample';
                end
            elseif contains(app.Radio_ConcErrsTE.SelectedObject.Text,'Errors')
                if (strcmp(Selection,'ID') || strcmp(Selection,'DC')) && app.Check_DC.Value == 1
                    app.Table_TEresults.Data = [dC_TE.DC.f_DC',dC_TE.(Selection).Sample'];
                else
                    app.Table_TEresults.Data = dC_TE.(Selection).Sample';
                end
            end
        end

        % Selection changed function: Radio_ConcErrsThU
        function ConcErrSwitchThU(app, event)
            Selection = getappdata(app.ICProUIFigure,'ActiveResultThU');
            ThU = getappdata(app.ICProUIFigure,'ThU');
            dThU = getappdata(app.ICProUIFigure,'dThU');
            SampleSeq = getappdata(app.ICProUIFigure,'SampleSeq');
            NatUSeq = getappdata(app.ICProUIFigure,'NatUSeq');
            if contains(app.Radio_ConcErrsThU.SelectedObject.Text,'Concentrations')
                if strcmp(Selection,'SampleUC')
                    app.Table_ThUresults.Data = [ThU.Sample.R229_230(2:end)',ThU.Sample.R236_234(2:end)'];
                elseif strcmp(Selection,'SampleMB')
                    app.Table_ThUresults.Data = [ThU.Sample.R229_230_cMB(2:end)',ThU.Sample.R236_234_cMB(2:end)'];
                elseif strcmp(Selection,'NatUUC')
                    app.Table_ThUresults.Data = ThU.NatU.R235_234(2:end);
                elseif strcmp(Selection,'NatUMB')
                    app.Table_ThUresults.Data = ThU.NatU.R235_234_cMB(2:end);
                end
            elseif contains(app.Radio_ConcErrsThU.SelectedObject.Text,'Errors')
                if strcmp(Selection,'SampleUC')
                    app.Table_ThUresults.Data = [dThU.Sample.R229_230(2:end)',dThU.Sample.R236_234(2:end)'];
                elseif strcmp(Selection,'SampleMB')
                    app.Table_ThUresults.Data = [dThU.Sample.R229_230_cMB(2:end)',dThU.Sample.R236_234_cMB(2:end)'];
                elseif strcmp(Selection,'NatUUC')
                    app.Table_ThUresults.Data = dThU.NatU.R235_234(2:end);
                elseif strcmp(Selection,'NatUMB')
                    app.Table_ThUresults.Data = dThU.NatU.R235_234_cMB(2:end);
                end
            end
            if strcmp(Selection,'SampleUC') || strcmp(Selection,'SampleMB')
                app.Table_ThUresults.ColumnName = {'Th229/Th230','U236/U234'};
                app.Table_ThUresults.RowName = SampleSeq(2:end);
            elseif strcmp(Selection,'NatUUC') || strcmp(Selection,'NatUMB')
                app.Table_ThUresults.ColumnName = {'U235/U234'};
                app.Table_ThUresults.RowName = NatUSeq;
            end
            setappdata(app.ICProUIFigure,'ActiveResultThU',Selection);
        end

        % Value changed function: Button_TEtoggle
        function ToggleTE(app, event)
            % Get Th229 spike fields (for un/toggling)
            Th229SpikeFields = findall(app.SpikesPanel,'-regexp','Tag','229');
            Th229SpikeFields(end+1) = app.Field_ThBC;
            selectedTab = app.TabGroup.SelectedTab;
            if app.Button_TEtoggle.Value == 0
                % UNTOGGLING TE ANALYSIS
                % Change toggle button color/icon & disable panel
                set(app.Button_TEtoggle,'Icon','misc/icons/x.png')
                set(app.Button_TEtoggle,'BackgroundColor',[1 .5 .5])
                set(app.Panel_TE,'Enable','off')
                % Switch to ThU results tab & enable Th229 spike fields
                if selectedTab == app.Tab_IDWeights || selectedTab == app.Tab_RawIntensities || selectedTab == app.Tab_TEresults || selectedTab == app.Tab_TEplot
                    app.TabGroup.SelectedTab = app.Tab_ThUresults;
                end
                for iF = 1 : length(Th229SpikeFields)
                    Th229SpikeFields(iF).Enable = 'on';
                end
            elseif app.Button_TEtoggle.Value == 1
                % TOGGLING TE ANALYSIS
                % Change toggle button color/icon & enable panel
                set(app.Button_TEtoggle,'Icon','misc/icons/tick.png')
                set(app.Button_TEtoggle,'BackgroundColor',[.5 1 .5])
                set(app.Panel_TE,'Enable','on')
                if app.Button_ThUtoggle.Value == 0
                    if app.Check_DC.Value == 0
                        % If ThU analysis and TE dilution correction are untoggled...
                        % Switch to ThU results tab & enable Th229 spike fields
                        if selectedTab == app.Tab_IDWeights || selectedTab == app.Tab_RawIntensities || selectedTab == app.Tab_TEresults || selectedTab == app.Tab_TEplot
                            app.TabGroup.SelectedTab = app.Tab_ThUresults;
                        end
                        for iF = 1 : length(Th229SpikeFields)
                            Th229SpikeFields(iF).Enable = 'on';
                        end
                    elseif app.Check_DC.Value == 1
                        % If ThU analysis is untoggled and TE dilution correction is toggled...
                        % ... enable ID panel & disable Th229 spike fields
                        set(app.Panel_IsotopicDilution,'Enable','on')
                        for iF = 1 : length(Th229SpikeFields)
                            Th229SpikeFields(iF).Enable = 'off';
                        end
                    end
                elseif app.Button_ThUtoggle.Value == 1
                    if app.Check_DC.Value == 0
                        % If ThU analysis is toggled and TE dilution correction is untoggled...
                        % Switch to ThU results tab & enable Th229 spike fields
                        if selectedTab == app.Tab_IDWeights || selectedTab == app.Tab_RawIntensities || selectedTab == app.Tab_TEresults || selectedTab == app.Tab_TEplot
                            app.TabGroup.SelectedTab = app.Tab_ThUresults;
                        end
                        for iF = 1 : length(Th229SpikeFields)
                            Th229SpikeFields(iF).Enable = 'on';
                        end
                    elseif app.Check_DC.Value == 1
                        % If ThU analysis and TE dilution correction are toggled...
                        % ... enable ID panel & Th229 spike fields
                        set(app.Panel_IsotopicDilution,'Enable','on')
                        for iF = 1 : length(Th229SpikeFields)
                            Th229SpikeFields(iF).Enable = 'on';
                        end
                    end
                end
            end
        end

        % Value changed function: Button_ThUtoggle
        function ToggleThU(app, event)
            % Get Th229 spike fields (for un/toggling)
            Th229SpikeFields = findall(app.SpikesPanel,'-regexp','Tag','229');
            Th229SpikeFields(end+1) = app.Field_ThBC;
            selectedTab = app.TabGroup.SelectedTab;
            if app.Button_ThUtoggle.Value == 0
                % UNTOGGLING ThU ANALYSIS
                % Change toggle button color/icon & disable panel
                set(app.Button_ThUtoggle,'Icon','misc/icons/x.png')
                set(app.Button_ThUtoggle,'BackgroundColor',[1 .5 .5])
                set(app.Panel_ThU,'Enable','off')
                if app.Button_TEtoggle.Value == 0
                    % If TE analysis is untoggled
                    % Switch to ThU results tab & enable Th229 spike fields
                    for iF = 1 : length(Th229SpikeFields)
                        Th229SpikeFields(iF).Enable = 'on';
                    end
                elseif app.Button_TEtoggle.Value == 1
                    if app.Check_DC.Value == 0
                        % If TE analysis is toggled & dilution correction is untoggled
                        % Switch to ThU results tab & enable Th229 spike fields
                        for iF = 1 : length(Th229SpikeFields)
                            Th229SpikeFields(iF).Enable = 'on';
                        end
                    elseif app.Check_DC.Value == 1
                        % If TE analysis & dilution correction are toggled...
                        % ... enable ID panel & disable Th229 spike fields
                        set(app.Panel_IsotopicDilution,'Enable','on')
                        for iF = 1 : length(Th229SpikeFields)
                            Th229SpikeFields(iF).Enable = 'off';
                        end
                    end
                end
                if selectedTab == app.Tab_ThUresults || selectedTab == app.IDvsLRThUTab
                    app.TabGroup.SelectedTab = app.Tab_IDWeights;
                end
            elseif app.Button_ThUtoggle.Value == 1
                % TOGGLING ThU ANALYSIS
                % Change toggle button color/icon & enable panel
                set(app.Button_ThUtoggle,'Icon','misc/icons/tick.png')
                set(app.Button_ThUtoggle,'BackgroundColor',[.5 1 .5])
                set(app.Panel_ThU,'Enable','on')
                if app.Button_TEtoggle.Value == 0
                    % If TE analysis is untoggled
                    % Switch to ThU results tab & enable Th229 spike fields
                    if selectedTab == app.Tab_ThUresults || selectedTab == app.IDvsLRThUTab
                        app.TabGroup.SelectedTab = app.Tab_IDWeights;
                    end
                    for iF = 1 : length(Th229SpikeFields)
                        Th229SpikeFields(iF).Enable = 'on';
                    end
                elseif app.Button_TEtoggle.Value == 1
                    if app.Check_DC.Value == 0
                        % If TE analysis is toggled & dilution correction is untoggled
                        % Switch to ThU results tab & enable Th229 spike fields
                        if selectedTab == app.Tab_ThUresults || selectedTab == app.IDvsLRThUTab
                            app.TabGroup.SelectedTab = app.Tab_IDWeights;
                        end
                        for iF = 1 : length(Th229SpikeFields)
                            Th229SpikeFields(iF).Enable = 'on';
                        end
                    elseif app.Check_DC.Value == 1
                        % If TE analysis & dilution correction are toggled
                        % ... enable ID panels & enable Th229 spike fields
                        set(app.Panel_IsotopicDilution,'Enable','on')
                        for iF = 1 : length(Th229SpikeFields)
                            Th229SpikeFields(iF).Enable = 'on';
                        end
                    end
                end
            end
        end

        % Value changed function: Check_RC
        function RCtoggle(app, event)
            value = app.Check_RC.Value;
            if value == 1
                app.RCButton.Enable = 1;
            else
                app.RCButton.Enable = 0;
            end
        end

        % Value changed function: Check_QC
        function QCtoggle(app, event)
            value = app.Check_QC.Value;
            if value == 1
                app.QCButton.Enable = 1;
            else
                app.QCButton.Enable = 0;
            end
        end

        % Value changed function: Check_SC
        function SCtoggle(app, event)
            value = app.Check_SC.Value;
            if value == 1
                app.SCButton.Enable = 1;
            else
                app.SCButton.Enable = 0;
            end
        end

        % Value changed function: Check_OC
        function OCtoggle(app, event)
            value = app.Check_OC.Value;
            if value == 1
                app.OCButton.Enable = 1;
            else
                app.OCButton.Enable = 0;
            end
        end

        % Value changed function: Check_BC
        function BCtoggle(app, event)
            value = app.Check_BC.Value;
            if value == 1
                app.BCButton.Enable = 1;
            else
                app.BCButton.Enable = 0;
            end
        end

        % Value changed function: Check_DC
        function DCtoggle(app, event)
            % Get Th229 spike fields (for un/toggling)
            Th229SpikeFields = findall(app.SpikesPanel,'-regexp','Tag','229');
            Th229SpikeFields(end+1) = app.Field_ThBC;
            selectedTab = app.TabGroup.SelectedTab;
            if app.Check_DC.Value == 0
                % UNTOGGLING TE DILUTION CORRECTION
                % Switch to ThU results tab & enable Th229 spike fields
                if selectedTab == app.Tab_IDWeights || selectedTab == app.Tab_RawIntensities || selectedTab == app.Tab_TEresults || selectedTab == app.Tab_TEplot
                    app.TabGroup.SelectedTab = app.Tab_ThUresults;
                end
                for iF = 1 : length(Th229SpikeFields)
                    Th229SpikeFields(iF).Enable = 'on';
                end
            elseif app.Check_DC.Value == 1
                % TOGGLING TE DILUTION CORRECTION
                if app.Button_ThUtoggle.Value == 0
                    % If ThU analysis is untoggled
                    % ... enable ID panel & disable Th229 spike fields
                    set(app.Panel_IsotopicDilution,'Enable','on')
                    for iF = 1 : length(Th229SpikeFields)
                        Th229SpikeFields(iF).Enable = 'off';
                    end
                elseif app.Button_ThUtoggle.Value == 1
                    % If ThU analysis is toggled
                    % ... enable ID panel & Th229 spike fields
                    set(app.Panel_IsotopicDilution,'Enable','on')
                    for iF = 1 : length(Th229SpikeFields)
                        Th229SpikeFields(iF).Enable = 'on';
                    end
                end
            end
        end

        % Button pushed function: Button_RawFile
        function BrowseTE(app, event)
            [TMRawFile,TMRawPath] = uigetfile('.xlsx','Pick a Trace Element raw file');
            if TMRawPath ~= 0
                set(app.Field_RawFile,'Value',[TMRawPath,TMRawFile])
            end
        end

        % Value changed function: Field_RawFile
        function NewInputFile(app, event)
            set(app.Button_MakeList,'Visible','on')
            set(app.Field_SampleList,'Visible','off')
        end

        % Button pushed function: Button_RawFolder
        function BrowseThU(app, event)
            ThURawPath = uigetdir('','Pick a Th/U raw data folder');
            if ThURawPath ~= 0
                set(app.Field_RawFolder,'Value',[ThURawPath,'/'])
            end
        end

        % Value changing function: Field_SIDTE
        function AutoFillSampleID(app, event)
            changingValue = event.Value;
            set(app.Field_SIDThU,'Value',changingValue)
        end

        % Button pushed function: Button_MakeList
        function MakeSampleList(app, event)
            if app.Button_TEtoggle.Value == 1 && app.Check_DC.Value == 1
                TEpar.RawPath = app.Field_RawFile.Value;
                TEpar.SID = app.Field_SIDTE.Value;
                TEpar.BID = app.Field_BlankID.Value;
                TEpar.VID = app.Field_SpikeID.Value;
                TEpar.RID = app.Field_RinseID.Value;
                TEpar.QID = app.Field_QCID.Value;
                if app.Button_TEtoggle.Value == 1
                    [~,~,~,RunID] = TE_ReadRaw(TEpar,0);
                end
                set(app.Button_MakeList,'Visible','off')
                set(app.Field_SampleList,'Visible','on')
                set(app.Field_SampleList,'Value',RunID.Sample)
                set(app.Field_cpsSampleList,'Visible','on')
                set(app.Field_cpsSampleList,'Value',RunID.Sample)
            elseif app.Button_ThUtoggle.Value == 1 && (app.Check_DC == 0 || app.Button_TEtoggle.Value == 0)
                FileList = dir([app.Field_RawFolder.Value,'*.txt']);
                RunID.all = {FileList.name}';
                RunID.Sample = RunID.all(contains(RunID.all,app.Field_SIDThU.Value));
                set(app.Button_MakeList,'Visible','off')
                set(app.Field_SampleList,'Visible','on')
                set(app.Field_SampleList,'Value',RunID.Sample)
                set(app.Field_cpsSampleList,'Visible','on')
                set(app.Field_cpsSampleList,'Value',RunID.Sample)
            end
        end

        % Drop down opening function: DropDown_229Th
        function getSpike229Th(app, event)
            opts = spreadsheetImportOptions('NumVariables',4,'Sheet','Th229','DataRange','A2','VariableNamesRange','A1');
            spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts);
            set(app.DropDown_229Th,'Items',['Select';spikes{:,1};'Add'])
            set(app.DropDown_229Th,'ItemsData',[0;(1:length(spikes{:,1}))';999])
        end

        % Value changed function: DropDown_229Th
        function setSpike229Th(app, event)
            value = app.DropDown_229Th.Value;
            opts = detectImportOptions('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx');
            spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts,'Sheet','Th229');
            if value > 0 && value < 999
                set(app.Field_229ThRY,'Value',spikes{value,2})
                set(app.Field_229ThRX,'Value',spikes{value,3})
                set(app.Field_229ThCY,'Value',spikes{value,4})
                set(app.Field_229ThCY,'Editable','off')
                set(app.Field_229ThRX,'Editable','off')
                set(app.Field_229ThRY,'Editable','off')
                set(app.Button_Edit229Th,'Visible','on')
                set(app.Button_Edit229Th,'Enable','on')
                set(app.Button_Remove229Th,'Enable','on')
                set(app.Button_Save229Th,'Visible','off')
                set(app.Button_Save229Th,'Enable','off')
            elseif value == 0
                set(app.Field_229ThRY,'Value',0)
                set(app.Field_229ThRX,'Value',0)
                set(app.Field_229ThCY,'Value',0)
                set(app.Field_229ThCY,'Editable','off')
                set(app.Field_229ThRX,'Editable','off')
                set(app.Field_229ThRY,'Editable','off')
                set(app.Button_Edit229Th,'Visible','on')
                set(app.Button_Edit229Th,'Enable','off')
                set(app.Button_Remove229Th,'Enable','off')
                set(app.Button_Save229Th,'Visible','off')
                set(app.Button_Save229Th,'Enable','off')
            elseif value == 999
                set(app.Field_229ThCY,'Editable','on')
                set(app.Field_229ThRX,'Editable','on')
                set(app.Field_229ThRY,'Editable','on')
                set(app.Button_Edit229Th,'Visible','off')
                set(app.Button_Edit229Th,'Enable','off')
                set(app.Button_Remove229Th,'Enable','off')
                set(app.Button_Save229Th,'Visible','on')
                set(app.Button_Save229Th,'Enable','on')
                newspikeID = inputdlg('Enter spike ID:');
                set(app.DropDown_229Th,'Items',['Select';spikes{:,1};newspikeID;'Add'])
                set(app.DropDown_229Th,'ItemsData',[0;(1:length(spikes{:,1})+1)';999])
                set(app.DropDown_229Th,'Value',length(spikes{:,1})+1)
                set(app.Field_229ThRY,'Value',0)
                set(app.Field_229ThRX,'Value',0)
                set(app.Field_229ThCY,'Value',0)
            end
        end

        % Button pushed function: Button_Edit229Th
        function edtSpike229Th(app, event)
            set(app.Field_229ThCY,'Editable','on')
            set(app.Field_229ThRX,'Editable','on')
            set(app.Field_229ThRY,'Editable','on')
            set(app.Button_Edit229Th,'Visible','off')
            set(app.Button_Edit229Th,'Enable','off')
            set(app.Button_Save229Th,'Visible','on')
            set(app.Button_Save229Th,'Enable','on')
        end

        % Button pushed function: Button_Save229Th
        function sveSpike229Th(app, event)
            value = app.DropDown_229Th.Value;
            opts = detectImportOptions('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx');
            spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts);
            if value > length(spikes{:,1})
                spikes = [spikes;{0,0,0,0}];
                spikes{end,1} = app.DropDown_229Th.Items(app.DropDown_229Th.Value+1);
            end
            spikes{value,2} = app.Field_229ThRY.Value;
            spikes{value,3} = app.Field_229ThRX.Value;
            spikes{value,4} = app.Field_229ThCY.Value;
            writetable(spikes,'/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx','Sheet','Th229','WriteMode','overwritesheet')
            set(app.Field_229ThCY,'Editable','off')
            set(app.Field_229ThRX,'Editable','off')
            set(app.Field_229ThRY,'Editable','off')
            set(app.Button_Edit229Th,'Visible','on')
            set(app.Button_Edit229Th,'Enable','on')
            set(app.Button_Remove229Th,'Enable','on')
            set(app.Button_Save229Th,'Visible','off')
            set(app.Button_Save229Th,'Enable','off')
        end

        % Button pushed function: Button_Remove229Th
        function dltSpike229Th(app, event)
            value = app.DropDown_229Th.Value;
            % Confirm dialog
            msg = ['Do you want to delete spike ''',app.DropDown_229Th.Items{value+1},''''];
            title = 'Confirm Delete';
            selection = questdlg(msg,title,'Ok','Cancel','Ok');
            if strcmp(selection,'Ok')
                opts = detectImportOptions('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx');
                spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts,'Sheet','Th229');
                spikes(value,:) = [];
                writetable(spikes,'/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx','Sheet','Th229','WriteMode','overwritesheet')
                set(app.DropDown_229Th,'Items',['Select';spikes{:,1};'Add'])
                set(app.DropDown_229Th,'ItemsData',[0;(1:length(spikes{:,1}))';999])
                app.DropDown_229Th.Value = 0;
                set(app.Field_229ThRY,'Value',0)
                set(app.Field_229ThRX,'Value',0)
                set(app.Field_229ThCY,'Value',0)
                set(app.Field_229ThCY,'Editable','off')
                set(app.Field_229ThRX,'Editable','off')
                set(app.Field_229ThRY,'Editable','off')
                set(app.Button_Edit229Th,'Visible','on')
                set(app.Button_Edit229Th,'Enable','off')
                set(app.Button_Remove229Th,'Enable','off')
                set(app.Button_Save229Th,'Visible','off')
                set(app.Button_Save229Th,'Enable','off')
            end
        end

        % Drop down opening function: DropDown_230Th
        function getSpike230Th(app, event)
            opts = spreadsheetImportOptions('NumVariables',4,'Sheet','Th230','DataRange','A2','VariableNamesRange','A1');
            spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts);
            set(app.DropDown_230Th,'Items',['Select';spikes{:,1};'Add'])
            set(app.DropDown_230Th,'ItemsData',[0;(1:length(spikes{:,1}))';999])
        end

        % Value changed function: DropDown_230Th
        function setSpike230Th(app, event)
            value = app.DropDown_230Th.Value;
            opts = detectImportOptions('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx');
            spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts,'Sheet','Th230');
            if value > 0 && value < 999
                set(app.Field_230ThRY,'Value',spikes{value,2})
                set(app.Field_230ThRX,'Value',spikes{value,3})
                set(app.Field_230ThCY,'Value',spikes{value,4})
                set(app.Field_230ThCY,'Editable','off')
                set(app.Field_230ThRX,'Editable','off')
                set(app.Field_230ThRY,'Editable','off')
                set(app.Button_Edit230Th,'Visible','on')
                set(app.Button_Edit230Th,'Enable','on')
                set(app.Button_Remove230Th,'Enable','on')
                set(app.Button_Save230Th,'Visible','off')
                set(app.Button_Save230Th,'Enable','off')
            elseif value == 0
                set(app.Field_230ThRY,'Value',0)
                set(app.Field_230ThRX,'Value',0)
                set(app.Field_230ThCY,'Value',0)
                set(app.Field_230ThCY,'Editable','off')
                set(app.Field_230ThRX,'Editable','off')
                set(app.Field_230ThRY,'Editable','off')
                set(app.Button_Edit230Th,'Visible','on')
                set(app.Button_Edit230Th,'Enable','off')
                set(app.Button_Remove230Th,'Enable','off')
                set(app.Button_Save230Th,'Visible','off')
                set(app.Button_Save230Th,'Enable','off')
            elseif value == 999
                set(app.Field_230ThCY,'Editable','on')
                set(app.Field_230ThRX,'Editable','on')
                set(app.Field_230ThRY,'Editable','on')
                set(app.Button_Edit230Th,'Visible','off')
                set(app.Button_Edit230Th,'Enable','off')
                set(app.Button_Remove230Th,'Enable','off')
                set(app.Button_Save230Th,'Visible','on')
                set(app.Button_Save230Th,'Enable','on')
                newspikeID = inputdlg('Enter spike ID:');
                set(app.DropDown_230Th,'Items',['Select';spikes{:,1};newspikeID;'Add'])
                set(app.DropDown_230Th,'ItemsData',[0;(1:length(spikes{:,1})+1)';999])
                set(app.DropDown_230Th,'Value',length(spikes{:,1})+1)
                set(app.Field_230ThRY,'Value',0)
                set(app.Field_230ThRX,'Value',0)
                set(app.Field_230ThCY,'Value',0)
            end
        end

        % Button pushed function: Button_Edit230Th
        function edtSpike230Th(app, event)
            set(app.Field_230ThCY,'Editable','on')
            set(app.Field_230ThRX,'Editable','on')
            set(app.Field_230ThRY,'Editable','on')
            set(app.Button_Edit230Th,'Visible','off')
            set(app.Button_Edit230Th,'Enable','off')
            set(app.Button_Save230Th,'Visible','on')
            set(app.Button_Save230Th,'Enable','on')
        end

        % Button pushed function: Button_Save230Th
        function sveSpike230Th(app, event)
            value = app.DropDown_230Th.Value;
            opts = detectImportOptions('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx');
            spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts,'Sheet','Th230');
            if value > length(spikes{:,1})
                spikes = [spikes;{0,0,0,0}];
                spikes{end,1} = app.DropDown_230Th.Items(app.DropDown_230Th.Value+1);
            end
            spikes{value,2} = app.Field_230ThRY.Value;
            spikes{value,3} = app.Field_230ThRX.Value;
            spikes{value,4} = app.Field_230ThCY.Value;
            writetable(spikes,'/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx','Sheet','Th230','WriteMode','overwritesheet')
            set(app.Field_230ThCY,'Editable','off')
            set(app.Field_230ThRX,'Editable','off')
            set(app.Field_230ThRY,'Editable','off')
            set(app.Button_Edit230Th,'Visible','on')
            set(app.Button_Edit230Th,'Enable','on')
            set(app.Button_Remove230Th,'Enable','on')
            set(app.Button_Save230Th,'Visible','off')
            set(app.Button_Save230Th,'Enable','off')
        end

        % Button pushed function: Button_Remove230Th
        function dltSpike230Th(app, event)
            value = app.DropDown_230Th.Value;
            % Confirm dialog
            msg = ['Do you want to delete spike ''',app.DropDown_230Th.Items{value+1},''''];
            title = 'Confirm Delete';
            selection = questdlg(msg,title,'Ok','Cancel','Ok');
            if strcmp(selection,'Ok')
                opts = detectImportOptions('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx');
                spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts,'Sheet','Th230');
                spikes(value,:) = [];
                writetable(spikes,'/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx','Sheet','Th230','WriteMode','overwritesheet')
                set(app.DropDown_230Th,'Items',['Select';spikes{:,1};'Add'])
                set(app.DropDown_230Th,'ItemsData',[0;(1:length(spikes{:,1}))';999])
                app.DropDown_230Th.Value = 0;
                set(app.Field_230ThRY,'Value',0)
                set(app.Field_230ThRX,'Value',0)
                set(app.Field_230ThCY,'Value',0)
                set(app.Field_230ThCY,'Editable','off')
                set(app.Field_230ThRX,'Editable','off')
                set(app.Field_230ThRY,'Editable','off')
                set(app.Button_Edit230Th,'Visible','on')
                set(app.Button_Edit230Th,'Enable','off')
                set(app.Button_Remove230Th,'Enable','off')
                set(app.Button_Save230Th,'Visible','off')
                set(app.Button_Save230Th,'Enable','off')
            end
        end

        % Drop down opening function: DropDown_236U
        function getSpikes236U(app, event)
            opts = spreadsheetImportOptions('NumVariables',4,'Sheet','U236','DataRange','A2','VariableNamesRange','A1');
            spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts);
            set(app.DropDown_236U,'Items',['Select';spikes{:,1};'Add'])
            set(app.DropDown_236U,'ItemsData',[0;(1:length(spikes{:,1}))';999])
        end

        % Value changed function: DropDown_236U
        function setSpike236U(app, event)
            value = app.DropDown_236U.Value;
            opts = detectImportOptions('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx');
            spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts,'Sheet','U236');
            if value > 0 && value < 999
                set(app.Field_236URY,'Value',spikes{value,2})
                set(app.Field_236URX,'Value',spikes{value,3})
                set(app.Field_236UCY,'Value',spikes{value,4})
                set(app.Field_236UCY,'Editable','off')
                set(app.Field_236URX,'Editable','off')
                set(app.Field_236URY,'Editable','off')
                set(app.Button_Edit236U,'Visible','on')
                set(app.Button_Edit236U,'Enable','on')
                set(app.Button_Remove236U,'Enable','on')
                set(app.Button_Save236U,'Visible','off')
                set(app.Button_Save236U,'Enable','off')
            elseif value == 0
                set(app.Field_236URY,'Value',0)
                set(app.Field_236URX,'Value',0)
                set(app.Field_236UCY,'Value',0)
                set(app.Field_236UCY,'Editable','off')
                set(app.Field_236URX,'Editable','off')
                set(app.Field_236URY,'Editable','off')
                set(app.Button_Edit236U,'Visible','on')
                set(app.Button_Edit236U,'Enable','off')
                set(app.Button_Remove236U,'Enable','off')
                set(app.Button_Save236U,'Visible','off')
                set(app.Button_Save236U,'Enable','off')
            elseif value == 999
                set(app.Field_236UCY,'Editable','on')
                set(app.Field_236URX,'Editable','on')
                set(app.Field_236URY,'Editable','on')
                set(app.Button_Edit236U,'Visible','off')
                set(app.Button_Edit236U,'Enable','off')
                set(app.Button_Remove236U,'Enable','off')
                set(app.Button_Save236U,'Visible','on')
                set(app.Button_Save236U,'Enable','on')
                newspikeID = inputdlg('Enter spike ID:');
                set(app.DropDown_236U,'Items',['Select';spikes{:,1};newspikeID;'Add'])
                set(app.DropDown_236U,'ItemsData',[0;(1:length(spikes{:,1})+1)';999])
                set(app.DropDown_236U,'Value',length(spikes{:,1})+1)
                set(app.Field_236URY,'Value',0)
                set(app.Field_236URX,'Value',0)
                set(app.Field_236UCY,'Value',0)
            end
        end

        % Button pushed function: Button_Edit236U
        function edtSpike236U(app, event)
            set(app.Field_236UCY,'Editable','on')
            set(app.Field_236URX,'Editable','on')
            set(app.Field_236URY,'Editable','on')
            set(app.Button_Edit236U,'Visible','off')
            set(app.Button_Edit236U,'Enable','off')
            set(app.Button_Save236U,'Visible','on')
            set(app.Button_Save236U,'Enable','on')
        end

        % Button pushed function: Button_Save236U
        function sveSpike236U(app, event)
            value = app.DropDown_236U.Value;
            opts = detectImportOptions('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx');
            spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts,'Sheet','U236');
            if value > length(spikes{:,1})
                spikes = [spikes;{0,0,0,0}];
                spikes{end,1} = app.DropDown_236U.Items(app.DropDown_236U.Value+1);
            end
            spikes{value,2} = app.Field_236URY.Value;
            spikes{value,3} = app.Field_236URX.Value;
            spikes{value,4} = app.Field_236UCY.Value;
            writetable(spikes,'/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx','Sheet','U236','WriteMode','overwritesheet')
            set(app.Field_236UCY,'Editable','off')
            set(app.Field_236URX,'Editable','off')
            set(app.Field_236URY,'Editable','off')
            set(app.Button_Edit236U,'Visible','on')
            set(app.Button_Edit236U,'Enable','on')
            set(app.Button_Remove236U,'Enable','on')
            set(app.Button_Save236U,'Visible','off')
            set(app.Button_Save236U,'Enable','off')
        end

        % Button pushed function: Button_Remove236U
        function dltSpike236U(app, event)
            value = app.DropDown_236U.Value;
            % Confirm dialog
            msg = ['Do you want to delete spike ''',app.DropDown_236U.Items{value+1},''''];
            title = 'Confirm Delete';
            selection = questdlg(msg,title,'Ok','Cancel','Ok');
            if strcmp(selection,'Ok')
                opts = detectImportOptions('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx');
                spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts,'Sheet','U236');
                spikes(value,:) = [];
                writetable(spikes,'/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx','Sheet','U236','WriteMode','overwritesheet')
                set(app.DropDown_236U,'Items',['Select';spikes{:,1};'Add'])
                set(app.DropDown_236U,'ItemsData',[0;(1:length(spikes{:,1}))';999])
                app.DropDown_236U.Value = 0;
                set(app.Field_236URY,'Value',0)
                set(app.Field_236URX,'Value',0)
                set(app.Field_236UCY,'Value',0)
                set(app.Field_236UCY,'Editable','off')
                set(app.Field_236URX,'Editable','off')
                set(app.Field_236URY,'Editable','off')
                set(app.Button_Edit236U,'Visible','on')
                set(app.Button_Edit236U,'Enable','off')
                set(app.Button_Remove236U,'Enable','off')
                set(app.Button_Save236U,'Visible','off')
                set(app.Button_Save236U,'Enable','off')
            end
        end

        % Selection change function: TabGroup
        function DisableTabs(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if app.Button_TEtoggle.Value == 0 || (app.Button_TEtoggle.Value == 1 && app.Check_DC.Value == 0)
                if selectedTab == app.Tab_IDWeights || selectedTab == app.Tab_RawIntensities || selectedTab == app.Tab_TEresults || selectedTab == app.Tab_TEplot
                    app.TabGroup.SelectedTab = event.OldValue;
                end
            elseif app.Button_ThUtoggle.Value == 0
                if selectedTab == app.Tab_ThUresults || selectedTab == app.IDvsLRThUTab
                    app.TabGroup.SelectedTab = event.OldValue;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ICProUIFigure = uifigure('Visible', 'off');
            app.ICProUIFigure.Name = 'ICPro';
            app.SampletypeSwitchLabel = uilabel(app.ICProUIFigure);
            app.SampletypeSwitchLabel.Text = 'Sample type';
            app.Panel_TE = uipanel(app.ICProUIFigure);
            app.Panel_TE.Title = 'Trace element data processing';
            app.Button_RawFile = uibutton(app.Panel_TE, 'push');
            app.Button_RawFile.ButtonPushedFcn = createCallbackFcn(app, @BrowseTE, true);
            app.Button_RawFile.Text = '...';
            app.Field_RawFile = uieditfield(app.Panel_TE, 'text');
            app.Field_RawFile.ValueChangedFcn = createCallbackFcn(app, @NewInputFile, true);
            app.Field_RawFile.Value = '/Users/jweis/Documents/Study/3 - MSC/RP - Guided Research (IMAS)/1 - Data/1 ICP-MS/BATCH 1/TM_B1_superraw.xlsx';
            app.Field_SIDTE = uieditfield(app.Panel_TE, 'text');
            app.Field_SIDTE.ValueChangingFcn = createCallbackFcn(app, @AutoFillSampleID, true);
            app.Field_SIDTE.Value = 'JW';
            app.Field_BlankID = uieditfield(app.Panel_TE, 'text');
            app.Field_BlankID.Value = '24_Blk';
            app.Field_SpikeID = uieditfield(app.Panel_TE, 'text');
            app.Field_SpikeID.Value = 'spike';
            app.Label_SIDTE = uilabel(app.Panel_TE);
            app.Label_SIDTE.Text = 'Sample ID initials';
            app.Label_BlankID = uilabel(app.Panel_TE);
            app.Label_BlankID.Text = 'Procedural blank ID';
            app.Label_SpikeID = uilabel(app.Panel_TE);
            app.Label_SpikeID.Text = 'Spiked sample ID';
            app.Field_RinseID = uieditfield(app.Panel_TE, 'text');
            app.Field_RinseID.Value = 'Rinse';
            app.Label_RinseID = uilabel(app.Panel_TE);
            app.Label_RinseID.Text = 'Rinse ID';
            app.Field_QCID = uieditfield(app.Panel_TE, 'text');
            app.Field_QCID.Value = 'QC';
            app.Label_QCID = uilabel(app.Panel_TE);
            app.Label_QCID.Text = 'QC ID';
            app.Panel_TECorr = uipanel(app.Panel_TE);
            app.Panel_TECorr.Title = 'Select corrections to apply';
            app.Check_RC = uicheckbox(app.Panel_TECorr);
            app.Check_RC.ValueChangedFcn = createCallbackFcn(app, @RCtoggle, true);
            app.Check_RC.Text = 'Rinse correction';
            app.Check_QC = uicheckbox(app.Panel_TECorr);
            app.Check_QC.ValueChangedFcn = createCallbackFcn(app, @QCtoggle, true);
            app.Check_QC.Text = 'QC correction';
            app.Check_QC.Value = true;
            app.Check_SC = uicheckbox(app.Panel_TECorr);
            app.Check_SC.ValueChangedFcn = createCallbackFcn(app, @SCtoggle, true);
            app.Check_SC.Text = 'Spike correction';
            app.Check_OC = uicheckbox(app.Panel_TECorr);
            app.Check_OC.ValueChangedFcn = createCallbackFcn(app, @OCtoggle, true);
            app.Check_OC.Text = 'Oxide correction';
            app.Check_BC = uicheckbox(app.Panel_TECorr);
            app.Check_BC.ValueChangedFcn = createCallbackFcn(app, @BCtoggle, true);
            app.Check_BC.Text = 'Blank correction';
            app.Check_BC.Value = true;
            app.Check_DC = uicheckbox(app.Panel_TECorr);
            app.Check_DC.ValueChangedFcn = createCallbackFcn(app, @DCtoggle, true);
            app.Check_DC.Text = 'Dilution correction';
            app.Check_DC.Value = true;
            app.Panel_ThU = uipanel(app.ICProUIFigure);
            app.Panel_ThU.Title = 'Th/U data processing';
            app.Field_NBlocks = uieditfield(app.Panel_ThU, 'text');
            app.Field_NBlocks.Value = '8';
            app.Field_NSamples = uieditfield(app.Panel_ThU, 'text');
            app.Field_NSamples.Value = '3';
            app.Label_NBlocks = uilabel(app.Panel_ThU);
            app.Label_NBlocks.Text = 'No. of analysis blocks';
            app.Label_NSamples = uilabel(app.Panel_ThU);
            app.Label_NSamples.Text = 'No. of samples per block';
            app.Field_ICPBlankID = uieditfield(app.Panel_ThU, 'text');
            app.Field_ICPBlankID.Value = 'Blank';
            app.Label_ICPBlankID = uilabel(app.Panel_ThU);
            app.Label_ICPBlankID.Text = 'ICP Blank ID';
            app.Field_ICPNatUID = uieditfield(app.Panel_ThU, 'text');
            app.Field_ICPNatUID.Value = 'NatU';
            app.Label_ICPNatUID = uilabel(app.Panel_ThU);
            app.Label_ICPNatUID.Text = 'ICP NatU ID';
            app.Field_SIDThU = uieditfield(app.Panel_ThU, 'text');
            app.Field_SIDThU.Value = 'JW';
            app.Label_SIDThU = uilabel(app.Panel_ThU);
            app.Label_SIDThU.Text = 'Sample ID initials';
            app.Button_RawFolder = uibutton(app.Panel_ThU, 'push');
            app.Button_RawFolder.ButtonPushedFcn = createCallbackFcn(app, @BrowseThU, true);
            app.Button_RawFolder.Text = '...';
            app.Field_RawFolder = uieditfield(app.Panel_ThU, 'text');
            app.Field_RawFolder.Value = '/Users/jweis/Documents/Study/3 - MSC/RP - Guided Research (IMAS)/1 - Data/1 ICP-MS/BATCH 1/190819_Jake_Ratios';
            app.Panel_IsotopicDilution = uipanel(app.ICProUIFigure);
            app.Panel_IsotopicDilution.Title = 'Isotopic Dilution Input & Results';
            app.SpikesPanel = uipanel(app.Panel_IsotopicDilution);
            app.SpikesPanel.Title = 'Spikes';
            app.UspikeDropDownLabel = uilabel(app.SpikesPanel);
            app.UspikeDropDownLabel.Text = '236U spike';
            app.DropDown_236U = uidropdown(app.SpikesPanel);
            app.DropDown_236U.Items = {'Select'};
            app.DropDown_236U.DropDownOpeningFcn = createCallbackFcn(app, @getSpikes236U, true);
            app.DropDown_236U.ValueChangedFcn = createCallbackFcn(app, @setSpike236U, true);
            app.DropDown_236U.Value = 'Select';
            app.ThspikeDropDown_2Label = uilabel(app.SpikesPanel);
            app.ThspikeDropDown_2Label.Text = '230Th spike';
            app.DropDown_230Th = uidropdown(app.SpikesPanel);
            app.DropDown_230Th.Items = {'Select'};
            app.DropDown_230Th.DropDownOpeningFcn = createCallbackFcn(app, @getSpike230Th, true);
            app.DropDown_230Th.ValueChangedFcn = createCallbackFcn(app, @setSpike230Th, true);
            app.DropDown_230Th.Value = 'Select';
            app.ThspikeDropDownLabel = uilabel(app.SpikesPanel);
            app.ThspikeDropDownLabel.Text = '229Th spike';
            app.DropDown_229Th = uidropdown(app.SpikesPanel);
            app.DropDown_229Th.Items = {'Select'};
            app.DropDown_229Th.DropDownOpeningFcn = createCallbackFcn(app, @getSpike229Th, true);
            app.DropDown_229Th.ValueChangedFcn = createCallbackFcn(app, @setSpike229Th, true);
            app.DropDown_229Th.Tag = 'DropDown_229Th';
            app.DropDown_229Th.Value = 'Select';
            app.Field_229ThRY = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_229ThRY.Tag = 'Field_229ThRY';
            app.Field_229ThRY.Editable = 'off';
            app.Label_RX = uilabel(app.SpikesPanel);
            app.Label_RX.Tooltip = {'Isotope amount ratio of sample'};
            app.Label_RX.Text = 'RX';
            app.Field_229ThRX = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_229ThRX.Tag = 'Field_229ThRX';
            app.Field_229ThRX.Editable = 'off';
            app.Label_CY = uilabel(app.SpikesPanel);
            app.Label_CY.Tooltip = {'spike concentration (pg/g)'};
            app.Label_CY.Text = 'CY';
            app.Field_229ThCY = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_229ThCY.Tag = 'Field_229ThCY';
            app.Field_229ThCY.Editable = 'off';
            app.Field_230ThRY = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_230ThRY.Editable = 'off';
            app.Field_236URY = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_236URY.Editable = 'off';
            app.Field_230ThRX = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_230ThRX.Editable = 'off';
            app.Field_236URX = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_236URX.Editable = 'off';
            app.Field_230ThCY = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_230ThCY.Editable = 'off';
            app.Field_236UCY = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_236UCY.Editable = 'off';
            app.Label_RY = uilabel(app.SpikesPanel);
            app.Label_RY.Tooltip = {'Isotope amount ratio of spike'};
            app.Label_RY.Text = 'RY';
            app.Button_Edit229Th = uibutton(app.SpikesPanel, 'push');
            app.Button_Edit229Th.ButtonPushedFcn = createCallbackFcn(app, @edtSpike229Th, true);
            app.Button_Edit229Th.Tag = 'Button_Edit229Th';
            app.Button_Edit229Th.Enable = 'off';
            app.Button_Edit229Th.Text = 'edit';
            app.Button_Edit230Th = uibutton(app.SpikesPanel, 'push');
            app.Button_Edit230Th.ButtonPushedFcn = createCallbackFcn(app, @edtSpike230Th, true);
            app.Button_Edit230Th.Enable = 'off';
            app.Button_Edit230Th.Text = 'edit';
            app.Button_Edit236U = uibutton(app.SpikesPanel, 'push');
            app.Button_Edit236U.ButtonPushedFcn = createCallbackFcn(app, @edtSpike236U, true);
            app.Button_Edit236U.Enable = 'off';
            app.Button_Edit236U.Text = 'edit';
            app.Button_Save229Th = uibutton(app.SpikesPanel, 'push');
            app.Button_Save229Th.ButtonPushedFcn = createCallbackFcn(app, @sveSpike229Th, true);
            app.Button_Save229Th.Tag = 'Button_Save229Th';
            app.Button_Save229Th.Enable = 'off';
            app.Button_Save229Th.Visible = 'off';
            app.Button_Save229Th.Text = 'save';
            app.Button_Save230Th = uibutton(app.SpikesPanel, 'push');
            app.Button_Save230Th.ButtonPushedFcn = createCallbackFcn(app, @sveSpike230Th, true);
            app.Button_Save230Th.Enable = 'off';
            app.Button_Save230Th.Visible = 'off';
            app.Button_Save230Th.Text = 'save';
            app.Button_Save236U = uibutton(app.SpikesPanel, 'push');
            app.Button_Save236U.ButtonPushedFcn = createCallbackFcn(app, @sveSpike236U, true);
            app.Button_Save236U.Enable = 'off';
            app.Button_Save236U.Visible = 'off';
            app.Button_Save236U.Text = 'save';
            app.Button_Remove229Th = uibutton(app.SpikesPanel, 'push');
            app.Button_Remove229Th.ButtonPushedFcn = createCallbackFcn(app, @dltSpike229Th, true);
            app.Button_Remove229Th.Tag = 'Button_Remove229Th';
            app.Button_Remove229Th.Enable = 'off';
            app.Button_Remove229Th.Text = 'remove';
            app.Button_Remove230Th = uibutton(app.SpikesPanel, 'push');
            app.Button_Remove230Th.ButtonPushedFcn = createCallbackFcn(app, @dltSpike230Th, true);
            app.Button_Remove230Th.Enable = 'off';
            app.Button_Remove230Th.Text = 'remove';
            app.Button_Remove236U = uibutton(app.SpikesPanel, 'push');
            app.Button_Remove236U.ButtonPushedFcn = createCallbackFcn(app, @dltSpike236U, true);
            app.Button_Remove236U.Enable = 'off';
            app.Button_Remove236U.Text = 'remove';
            app.Footnote = uilabel(app.SpikesPanel);
            app.Footnote.Tooltip = {''};
            app.Footnote.Text = 'RY: isotope amount ratio of spike, RX: isotope amount ratio of sample, CY: spike concentration (pg/g)';
            app.TabGroup = uitabgroup(app.Panel_IsotopicDilution);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @DisableTabs, true);
            app.Tab_IDWeights = uitab(app.TabGroup);
            app.Tab_IDWeights.Title = 'Weights [mg]';
            app.Field_Sediment = uitextarea(app.Tab_IDWeights);
            app.Field_Sediment.Tag = 'Field_Sediment';
            app.Field_Sediment.Value = {'184.1'; '196.8'; '200.0'; '180.8'; '176.2'; '204.6'; '194.2'; '180.8'; '224.5'; '171.9'; '185.2'; '216.5'; '170.6'; '192.7'; '229.4'; '219.7'; '199.7'; '213.3'; '194.6'; '215.1'; '207.3'; '177.4'; '214.5'};
            app.Label_Sediment = uilabel(app.Tab_IDWeights);
            app.Label_Sediment.Text = 'Sediment';
            app.Field_4ml = uitextarea(app.Tab_IDWeights);
            app.Field_4ml.Tag = 'Field_4ml';
            app.Field_4ml.Value = {'5197.7'; '5225.1'; '5212.2'; '5206.8'; '5195.3'; '5232.5'; '5213.0'; '5193.2'; '5029.9'; '5281.8'; '5109.8'; '5310.3'; '5173.4'; '5237.7'; '5078.1'; '5321.3'; '5265.7'; '5270.7'; '5243.2'; '5287.9'; '5306.2'; '5194.9'; '5184.1'};
            app.Field_10ml = uitextarea(app.Tab_IDWeights);
            app.Field_10ml.Tag = 'Field_10ml';
            app.Field_10ml.Value = {'10405.5'; '10692.3'; '10674.7'; '10562.8'; '10564.1'; '10546.3'; '10563.6'; '10615.1'; '10593.9'; '10534.2'; '10513.2'; '10738.7'; '10731.0'; '10777.3'; '11358.9'; '10894.5'; '10899.8'; '10942.8'; '10917.6'; '10923.6'; '10916.0'; '10903.6'; '10898.8'};
            app.Field_400ul = uitextarea(app.Tab_IDWeights);
            app.Field_400ul.Tag = 'Field_400ul';
            app.Field_400ul.Value = {'523.4'; '520.4'; '522.8'; '523.1'; '525.3'; '521.2'; '521.5'; '521.4'; '514.1'; '524.3'; '517.8'; '527.2'; '524.6'; '523.8'; '524.2'; '527.5'; '525.7'; '528.2'; '524.8'; '525.5'; '525.9'; '524.5'; '517.0'};
            app.Field_ThBC = uitextarea(app.Tab_IDWeights);
            app.Field_ThBC.Tag = 'Field_ThBC';
            app.Field_ThBC.Value = {'508.7'; '502.3'; '505.7'; '504.5'; '504.9'; '508.3'; '508.0'; '507.4'; '511.2'; '508.5'; '506.9'; '509.1'; '510.7'; '510.4'; '509.3'; '513.0'; '510.5'; '514.5'; '510.9'; '508.0'; '511.0'; '508.8'; '510.5'};
            app.Label_4ml = uilabel(app.Tab_IDWeights);
            app.Label_4ml.Text = '4 ml';
            app.Label_10ml = uilabel(app.Tab_IDWeights);
            app.Label_10ml.Text = '10 ml';
            app.Label_400ul = uilabel(app.Tab_IDWeights);
            app.Label_400ul.Text = '400 ul';
            app.Label_ThBC = uilabel(app.Tab_IDWeights);
            app.Label_ThBC.Text = '229Th (BC)';
            app.Field_UBC = uitextarea(app.Tab_IDWeights);
            app.Field_UBC.Tag = 'Field_UBC';
            app.Field_UBC.Value = {'30.1'; '28.3'; '26.8'; '26.7'; '26.3'; '28.8'; '27.8'; '28.0'; '29.7'; '28.3'; '29.7'; '28.3'; '28.0'; '28.4'; '28.6'; '29.0'; '28.7'; '30.2'; '28.9'; '26.7'; '29.2'; '29.1'; '28.1'};
            app.Field_ThSC = uitextarea(app.Tab_IDWeights);
            app.Field_ThSC.Tag = 'Field_ThSC';
            app.Field_ThSC.Value = {'41.4'; '41.2'; '41.3'; '41.1'; '41.5'; '41.7'; '41.5'; '41.6'; '41.4'; '41.4'; '41.7'; '41.7'; '41.7'; '41.6'; '41.7'; '41.6'; '41.7'; '41.6'; '41.5'; '41.3'; '41.5'; '41.4'; '41.6'};
            app.Label_UBC = uilabel(app.Tab_IDWeights);
            app.Label_UBC.Text = '236U (BC)';
            app.Label_ThSC = uilabel(app.Tab_IDWeights);
            app.Label_ThSC.Text = '230Th (SC)';
            app.Field_USC = uitextarea(app.Tab_IDWeights);
            app.Field_USC.Tag = 'Field_USC';
            app.Field_USC.Value = {'154.6'; '154.2'; '153.1'; '154.9'; '153.1'; '153.8'; '154.7'; '153.5'; '153.5'; '154.4'; '154.6'; '153.0'; '153.8'; '153.0'; '152.9'; '155.1'; '154.9'; '155.0'; '154.5'; '154.6'; '154.0'; '154.6'; '154.8'};
            app.Label_USC = uilabel(app.Tab_IDWeights);
            app.Label_USC.Text = '236U (SC)';
            app.Field_SampleList = uitextarea(app.Tab_IDWeights);
            app.Field_SampleList.Tag = 'Field_SampleList';
            app.Field_SampleList.Editable = 'off';
            app.Field_SampleList.Visible = 'off';
            app.Label_SampleList = uilabel(app.Tab_IDWeights);
            app.Label_SampleList.Text = 'ID';
            app.Button_MakeList = uibutton(app.Tab_IDWeights, 'push');
            app.Button_MakeList.ButtonPushedFcn = createCallbackFcn(app, @MakeSampleList, true);
            app.Button_MakeList.Text = 'Generate';
            app.Tab_RawIntensities = uitab(app.TabGroup);
            app.Tab_RawIntensities.Title = 'Th & U raw intensities [cps]';
            app.Field_cpsSampleList = uitextarea(app.Tab_RawIntensities);
            app.Field_cpsSampleList.Editable = 'off';
            app.Field_cpsSampleList.Visible = 'off';
            app.Label_cpsSampleList = uilabel(app.Tab_RawIntensities);
            app.Label_cpsSampleList.Text = 'ID';
            app.Field_cps230Th = uitextarea(app.Tab_RawIntensities);
            app.Field_cps230Th.Value = {'8047.2'; '8505.1'; '8236.9'; '8737.9'; '8742.9'; '8662.0'; '9355.2'; '8975.7'; '9360.2'; '8656.9'; '9524.7'; '8502.6'; '9557.6'; '10028.2'; '8942.8'; '9534.8'; '9259.1'; '9114.8'; '9747.3'; '9211.0'; '9173.0'; '8993.4'; '9590.5'};
            app.Label_cps230Th = uilabel(app.Tab_RawIntensities);
            app.Label_cps230Th.Text = 'Th230';
            app.Field_cps232Th = uitextarea(app.Tab_RawIntensities);
            app.Field_cps232Th.Value = {'26456.4'; '186989.7'; '27954.4'; '73609.2'; '84796.8'; '114305.2'; '110239.3'; '89902.2'; '887701.4'; '30664.3'; '690373'; '37714'; '32405.2'; '33554'; '31345'; '27562.1'; '32212.9'; '31934.6'; '31856.1'; '36405.8'; '34664.8'; '35181.1'; '393334.1'};
            app.Field_cps236U = uitextarea(app.Tab_RawIntensities);
            app.Field_cps236U.Value = {'33442.7'; '33354.1'; '34814.1'; '35520.1'; '35742.8'; '36345.1'; '37438.2'; '35651.8'; '38751.5'; '36233.7'; '37739.3'; '35219'; '37633.1'; '40710.2'; '35973.1'; '38159.4'; '36752.5'; '37053.6'; '37807.7'; '36256.5'; '37418'; '36152.8'; '37779.8'};
            app.Field_cps238U = uitextarea(app.Tab_RawIntensities);
            app.Field_cps238U.Value = {'24123.5'; '46464.7'; '10293.8'; '23949'; '27321.9'; '75431.8'; '76269.6'; '83847.6'; '201936.9'; '12712.5'; '111072.6'; '13570.2'; '16606.4'; '19088.4'; '18939.1'; '20017'; '26001'; '19538.8'; '18827.9'; '21808.4'; '15171.8'; '15014.9'; '299990.2'};
            app.Label_cps232Th = uilabel(app.Tab_RawIntensities);
            app.Label_cps232Th.Text = '232Th';
            app.Label_cps236U = uilabel(app.Tab_RawIntensities);
            app.Label_cps236U.Text = '236U';
            app.Label_cps238U = uilabel(app.Tab_RawIntensities);
            app.Label_cps238U.Text = '238U';
            app.Label_cps230Th_R = uilabel(app.Tab_RawIntensities);
            app.Label_cps230Th_R.Text = 'Th230 Rinse';
            app.Label_cps232Th_R = uilabel(app.Tab_RawIntensities);
            app.Label_cps232Th_R.Text = '232Th Rinse';
            app.Label_cps236U_R = uilabel(app.Tab_RawIntensities);
            app.Label_cps236U_R.Text = '236U Rinse';
            app.Label_cps238U_R = uilabel(app.Tab_RawIntensities);
            app.Label_cps238U_R.Text = '238U Rinse';
            app.Field_cps230Th_R = uitextarea(app.Tab_RawIntensities);
            app.Field_cps230Th_R.Value = {'optional'};
            app.Field_cps232Th_R = uitextarea(app.Tab_RawIntensities);
            app.Field_cps232Th_R.Value = {'optional'};
            app.Field_cps236U_R = uitextarea(app.Tab_RawIntensities);
            app.Field_cps236U_R.Value = {'optional'};
            app.Field_cps238U_R = uitextarea(app.Tab_RawIntensities);
            app.Field_cps238U_R.Value = {'optional'};
            app.Footnote2 = uilabel(app.Tab_RawIntensities);
            app.Footnote2.Text = 'Rinse raw intensities are required for rinse correction. If not specified, Th and U raw intensities will not be rinse corrected.';
            app.Tab_TEresults = uitab(app.TabGroup);
            app.Tab_TEresults.Title = 'Results (TE)';
            app.DataSelectTE = uibuttongroup(app.Tab_TEresults);
            app.DataSelectTE.SelectionChangedFcn = createCallbackFcn(app, @SwitchResultTE, true);
            app.RawButton = uitogglebutton(app.DataSelectTE);
            app.RawButton.Tag = 'Raw';
            app.RawButton.Text = 'Raw concentrations';
            app.RCButton = uitogglebutton(app.DataSelectTE);
            app.RCButton.Tag = 'RC';
            app.RCButton.Enable = 'off';
            app.RCButton.Text = 'Rinse corrected';
            app.QCButton = uitogglebutton(app.DataSelectTE);
            app.QCButton.Tag = 'QC';
            app.QCButton.Text = 'QC corrected';
            app.SCButton = uitogglebutton(app.DataSelectTE);
            app.SCButton.Tag = 'SC';
            app.SCButton.Enable = 'off';
            app.SCButton.Text = 'Spike corrected';
            app.OCButton = uitogglebutton(app.DataSelectTE);
            app.OCButton.Tag = 'OC';
            app.OCButton.Enable = 'off';
            app.OCButton.Text = 'Oxide corrected';
            app.BCButton = uitogglebutton(app.DataSelectTE);
            app.BCButton.Tag = 'BC';
            app.BCButton.Text = 'Blank corrected';
            app.DCButton = uitogglebutton(app.DataSelectTE);
            app.DCButton.Tag = 'DC';
            app.DCButton.Text = 'Dilution corrected';
            app.IDButton = uitogglebutton(app.DataSelectTE);
            app.IDButton.Tag = 'ID';
            app.IDButton.Text = 'Isotopic Dilution';
            app.IDButton.Value = true;
            app.Table_TEresults = uitable(app.Tab_TEresults);
            app.Table_TEresults.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Table_TEresults.RowName = {};
            app.Radio_ConcErrsTE = uibuttongroup(app.Tab_TEresults);
            app.Radio_ConcErrsTE.SelectionChangedFcn = createCallbackFcn(app, @ConcErrSwitchTE, true);
            app.Conc = uiradiobutton(app.Radio_ConcErrsTE);
            app.Conc.Text = '  Concentrations';
            app.Conc.Value = true;
            app.ErrsTE = uiradiobutton(app.Radio_ConcErrsTE);
            app.ErrsTE.Text = '  Errors';
            app.Button_SaveTEresults = uibutton(app.Tab_TEresults, 'push');
            app.Button_SaveTEresults.Text = 'save';
            app.Tab_TEplot = uitab(app.TabGroup);
            app.Tab_TEplot.Title = 'ID vs LR (TE)';
            app.SelectDropDown = uidropdown(app.Tab_TEplot);
            app.SelectDropDown.Items = {'Th232', 'U238'};
            app.SelectDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangePlot, true);
            app.SelectDropDown.Value = 'Th232';
            app.ExportfigureButton = uibutton(app.Tab_TEplot, 'push');
            app.ExportfigureButton.Text = 'Export figure';
            app.ThIDFig = uiaxes(app.Tab_TEplot);
            xlabel(app.ThIDFig, 'Linear calibration')
            ylabel(app.ThIDFig, 'Isotopic dilution')
            zlabel(app.ThIDFig, 'Z')
            app.UIDFig = uiaxes(app.Tab_TEplot);
            xlabel(app.UIDFig, 'Linear calibration')
            ylabel(app.UIDFig, 'Isotopic dilution')
            zlabel(app.UIDFig, 'Z')
            app.UIDFig.Visible = 'off';
            app.Tab_ThUresults = uitab(app.TabGroup);
            app.Tab_ThUresults.Title = 'Results (Th & U)';
            app.Table_ThUresults = uitable(app.Tab_ThUresults);
            app.Table_ThUresults.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Table_ThUresults.RowName = {};
            app.DataSelectThU = uibuttongroup(app.Tab_ThUresults);
            app.DataSelectThU.SelectionChangedFcn = createCallbackFcn(app, @SwitchResultThU, true);
            app.UCSButton = uitogglebutton(app.DataSelectThU);
            app.UCSButton.Tag = 'SampleUC';
            app.UCSButton.Text = 'uncorrected';
            app.MBSButton = uitogglebutton(app.DataSelectThU);
            app.MBSButton.Tag = 'SampleMB';
            app.MBSButton.Text = 'mass bias corrected';
            app.MBSButton.Value = true;
            app.UCNButton = uitogglebutton(app.DataSelectThU);
            app.UCNButton.Tag = 'NatUUC';
            app.UCNButton.Text = 'uncorrected';
            app.MBNButton = uitogglebutton(app.DataSelectThU);
            app.MBNButton.Tag = 'NatUMB';
            app.MBNButton.Text = 'mass bias corrected';
            app.NatUU235U234Label = uilabel(app.DataSelectThU);
            app.NatUU235U234Label.Text = 'NatU U235/U234';
            app.Radio_ConcErrsThU = uibuttongroup(app.Tab_ThUresults);
            app.Radio_ConcErrsThU.SelectionChangedFcn = createCallbackFcn(app, @ConcErrSwitchThU, true);
            app.ConcThU = uiradiobutton(app.Radio_ConcErrsThU);
            app.ConcThU.Text = '  Concentrations';
            app.ConcThU.Value = true;
            app.ErrsThU = uiradiobutton(app.Radio_ConcErrsThU);
            app.ErrsThU.Text = '  Errors';
            app.Button_SaveThUresults = uibutton(app.Tab_ThUresults, 'push');
            app.Button_SaveThUresults.Text = 'save';
            app.Th229230U236234Label = uilabel(app.Tab_ThUresults);
            app.Th229230U236234Label.Text = 'Th229/230, U236/234';
            app.Tab_Fluxes = uitab(app.TabGroup);
            app.Tab_Fluxes.Title = 'Fluxes';
            app.Button_TEtoggle = uibutton(app.ICProUIFigure, 'state');
            app.Button_TEtoggle.ValueChangedFcn = createCallbackFcn(app, @ToggleTE, true);
            app.Button_TEtoggle.Tooltip = {''};
            app.Button_TEtoggle.Icon = 'tick.png';
            app.Button_TEtoggle.Text = '';
            app.Button_TEtoggle.Value = true;
            app.Button_ThUtoggle = uibutton(app.ICProUIFigure, 'state');
            app.Button_ThUtoggle.ValueChangedFcn = createCallbackFcn(app, @ToggleThU, true);
            app.Button_ThUtoggle.Tooltip = {''};
            app.Button_ThUtoggle.Icon = 'tick.png';
            app.Button_ThUtoggle.Text = '';
            app.Button_ThUtoggle.Value = true;
            app.Button_Run = uibutton(app.ICProUIFigure, 'push');
            app.Button_Run.ButtonPushedFcn = createCallbackFcn(app, @Execute, true);
            app.Button_Run.Icon = 'go.png';
            app.Button_Run.Text = '';
            app.Switch_SType = uiswitch(app.ICProUIFigure, 'slider');
            app.Switch_SType.Items = {'Sediment', 'Sea water'};
            app.Switch_SType.Value = 'Sediment';
            % Show the figure after all components are created
            app.ICProUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICPro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ICProUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ICProUIFigure)
        end
    end
end